classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HomePanel                     matlab.ui.container.Panel
        HomePanel_ContinueButton      matlab.ui.control.Button
        SelectDirectoryButton         matlab.ui.control.Button
        SelectDirectoryTextArea       matlab.ui.control.TextArea
        HomePanel_Label1              matlab.ui.control.Label
        CroppingPanel                 matlab.ui.container.Panel
        InthepopupwindowselectaregionandthendoubleclicktoconfirmLabel  matlab.ui.control.Label
        ThresholdPanel                matlab.ui.container.Panel
        ThresholdPanelLabel_3         matlab.ui.control.Label
        ThresholdPanelContinueButton  matlab.ui.control.Button
        ThresholdPanelBackButton      matlab.ui.control.Button
        ThresholdPanelLabel_2         matlab.ui.control.Label
        ThresholdPanelValueEditField  matlab.ui.control.NumericEditField
        ThresholdValueEditFieldLabel  matlab.ui.control.Label
        MaskingPanel                  matlab.ui.container.Panel
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        DilationPanel                 matlab.ui.container.Panel
        DilationPanel_ContinueButton  matlab.ui.control.Button
        DilationPanel_SizeEditField   matlab.ui.control.NumericEditField
        WhatsizestructuringelementdoyouwanttousetodilatethemaskLabel  matlab.ui.control.Label
        DilationPanel_Label2          matlab.ui.control.Label
        DilationPanel_Label1          matlab.ui.control.Label
        CappingPanel                  matlab.ui.container.Panel
        CappingPanel_Label1           matlab.ui.control.Label
        RunComputationPanel           matlab.ui.container.Panel
        CapsandMaskreadyClickRuntostartthecomputationLabel  matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        SliceProgressPanel            matlab.ui.container.Panel
        SaveResultsButton             matlab.ui.control.Button
        SliceProgressPanel_TextArea   matlab.ui.control.TextArea
        ProgressTextAreaLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        currentDir = fileparts(mfilename('fullpath'));    
        defaultUigetdir
        filePath
    end
    
    properties (Access = public)
        waitingForInput = false;
        img
        croppingArea
        thresholdValue
        filterSize
        smoothingStructuringElementSize
        fillHolesAndClean
        e
        seg
        bw_caps
        mask
        area
        diam
        dist_caps
    end
    
    methods (Access = private)
        
        function diam = calculate_diam(app, A)            
            stats = regionprops(app.bw_caps,'Centroid');
            app.dist_caps = ((stats(1).Centroid(1)-stats(2).Centroid(1)).^2+(stats(1).Centroid(2)-stats(2).Centroid(2)).^2).^0.5;
            diam = A / app.dist_caps;
            t = 1:length(diam);
            figure, plot(t,diam), ylabel('diam (pixels)'), xlabel('time (s)')
            
            fs = 1 / mean(diff(t));
            %figure, spectrogram(highpass(A,0.5,fs),round(15*fs),[],[],fs,'yaxis'), title('Area')
            %figure, spectrogram(highpass(squeeze(mean(app.img,[1 2])),0.5,fs),round(15*fs),[],[],fs,'yaxis'), title('MPI')
            %figure, spectrogram(A,round(15*fs),[],[],fs,'yaxis'), title('Area')
            %figure, spectrogram(squeeze(mean(app.img,[1 2])),round(15*fs),[],[],fs,'yaxis'), title('MPI')
        end
        
        function A = calculate_area(app)
            A = squeeze(sum(app.seg,[1 2]));
            figure, set(gcf,'color','w')
            %plot(A), ylabel('Area (pixels)'), xlabel('frame #')
        end
        
    end
    
    methods (Access = public)
        
        function [] = closePopup(app)
            figs = findall(0, 'type', 'figure');
            numFigs = length(figs);
            for k = 1:numFigs
                if ~strcmp(figs(k).Name, 'MATLAB App')
                    close(figs(k))
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add the functions/ and utilities/ subdirectories to the path
            addpath(fullfile(app.currentDir, 'func'), fullfile(app.currentDir, 'util'));
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            f = figure('Position', [-100 -100 0 0]); % create a dummy figure so that uigetfile doesn't minimize our GUI
            %selectedDir = uigetdir(app.currentDir);
            [selectedFile, parentFolder] = uigetfile('*.mat');
            %[parentFolder, ~, ~] = fileparts(string(selectedDir));

            app.defaultUigetdir = parentFolder;
            % Check if a directory was selected (uigetdir returns 0 if the user cancels the dialog)
            delete(f);
            if selectedFile ~= 0
                % Store the selected directory path in the Edit Field
                app.filePath = fullfile(parentFolder, selectedFile);
                app.SelectDirectoryTextArea.Value = app.filePath;
            end
        end

        % Button pushed function: HomePanel_ContinueButton
        function HomePanel_ContinueButtonPushed(app, event)
            app.img = load(app.filePath).img;

            % cropping
            app.CroppingPanel.Visible = 'on';
            app.HomePanel.Visible = 'off';
            imagei(app.img), colormap gray
            roi = drawrectangle();
            wait(roi);
            rect=round(roi.Position);
            app.croppingArea = rect;
            app.img = app.img(rect(2):rect(2)+rect(4),rect(1):rect(1)+rect(3),:);
            closePopup(app)
            app.ThresholdPanel.Visible = 'on';
            app.CroppingPanel.Visible = 'off';
            app.waitingForInput = true;
            [app.e, app.seg, app.bw_caps, app.mask] = find_img_edges(app, app.img, [], 1);
        end

        % Button pushed function: ThresholdPanelBackButton
        function ThresholdPanelBackButtonPushed(app, event)
            app.waitingForInput = false;
            app.ThresholdPanel.Visible = 'off';
            app.HomePanel.Visible = 'off';
        end

        % Button pushed function: ThresholdPanelContinueButton
        function ThresholdPanelContinueButtonPushed(app, event)
            app.ThresholdPanelLabel_3.Text = class(app.thresholdValue);
            app.waitingForInput = false;
            app.ThresholdPanel.Visible = 'off';
            app.MaskingPanel.Visible = 'on';
        end

        % Value changed function: ThresholdPanelValueEditField
        function ThresholdPanelValueEditFieldValueChanged(app, event)
            value = app.ThresholdPanelValueEditField.Value;
            app.thresholdValue = value;
        end

        % Callback function
        function FilterSizeEditFieldValueChanged(app, event)
            value = app.FilterSizeEditField.Value;
            app.filterSize = value;
        end

        % Callback function
        function SmoothingStructuringElementSizeEditFieldValueChanged(app, event)
            value = app.SmoothingStructuringElementSizeEditField.Value;
            app.smoothingStructuringElementSize = value;
        end

        % Callback function
        function Fillholesandclean1foryes0fornoEditFieldValueChanged(app, event)
            value = app.Fillholesandclean1foryes0fornoEditField.Value;
            app.fillHolesAndClean = value;
        end

        % Button pushed function: DilationPanel_ContinueButton
        function DilationPanel_ContinueButtonPushed(app, event)
            app.waitingForInput = false;
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            f = figure('Position', [-100 -100 0 0]); % create a dummy figure so that uigetfile doesn't minimize our GUI
            %selectedDir = uigetdir(app.currentDir);
            folder = uigetdir;
            % Check if a directory was selected (uigetdir returns 0 if the user cancels the dialog)
            delete(f);
            if folder ~= 0
                % Define the filename
                filename = 'pulsatility_results.mat';
                
                % Create the full file path
                full_file_path = fullfile(folder, filename);
                full_file_path = string(full_file_path);
                %app.SliceProgressPanel_Label.Text = full_file_path;
                % Save the data
                croppingArea = app.croppingArea;
                area = app.area;
                bw_caps = app.bw_caps;
                mask = app.mask;
                e = app.e;
                seg = app.seg;
                diam = app.diam;
                dist_caps = app.dist_caps;
                save(full_file_path, ...
                    'croppingArea', ...
                    'area', ...
                    'bw_caps', ...
                    'mask', ...
                    'e', ...
                    'seg', ...
                    'diam', ...
                    'dist_caps' ...
                );
                message = strcat("results saved to ", full_file_path);
                title = 'Results Saved';
                icon = 'success';
                uialert(app.UIFigure, message, title, 'Icon', icon);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunComputationPanel.Visible = 'off'; % gather the user input from panel_2
            app.SliceProgressPanel.Visible = 'on';

            app.SliceProgressPanel_TextArea.Value = 'Masks and caps created.';
            message = 'calculating area and diameter.';
            app.SliceProgressPanel_TextArea.Value = [app.SliceProgressPanel_TextArea.Value(:)', {message}];
            app.waitingForInput = true;
            [app.e, app.seg, app.bw_caps, app.mask] = find_img_edges(app, app.img, app.bw_caps, app.mask);
            app.area = calculate_area(app);
            app.diam = calculate_diam(app, app.area);
            message = 'Done.';
            app.SliceProgressPanel_TextArea.Value = [app.SliceProgressPanel_TextArea.Value(:)', {message}];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SliceProgressPanel = uipanel(app.UIFigure);
            app.SliceProgressPanel.Title = 'Slice Progress';
            app.ProgressTextAreaLabel = uilabel(app.SliceProgressPanel);
            app.ProgressTextAreaLabel.Text = 'Progress';
            app.SliceProgressPanel_TextArea = uitextarea(app.SliceProgressPanel);
            app.SaveResultsButton = uibutton(app.SliceProgressPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.RunComputationPanel = uipanel(app.UIFigure);
            app.RunComputationPanel.Title = 'Computation';
            app.RunButton = uibutton(app.RunComputationPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CapsandMaskreadyClickRuntostartthecomputationLabel = uilabel(app.RunComputationPanel);
            app.CapsandMaskreadyClickRuntostartthecomputationLabel.Text = 'Caps and Mask ready. Click Run to start the computation.';
            app.CappingPanel = uipanel(app.UIFigure);
            app.CappingPanel.Title = 'Capping';
            app.CappingPanel_Label1 = uilabel(app.CappingPanel);
            app.CappingPanel_Label1.Text = 'Click and drag to draw a line to cap off the ends. Move the line and dbl click when satisfied. (Depending on the version of Matlab, you might have to move the end points';
            app.DilationPanel = uipanel(app.UIFigure);
            app.DilationPanel.Title = 'Dilation';
            app.DilationPanel_Label1 = uilabel(app.DilationPanel);
            app.DilationPanel_Label1.Text = 'Mask Step 3: dilate the mask to include the edges in all frames.';
            app.DilationPanel_Label2 = uilabel(app.DilationPanel);
            app.DilationPanel_Label2.Text = 'The current mask is in red. The edges are shown in green. Make sure the mask includes all of the edges of interest. I often start by dilating with a structuring element of size 5 pixels. Enter 0 to stop dilating.';
            app.WhatsizestructuringelementdoyouwanttousetodilatethemaskLabel = uilabel(app.DilationPanel);
            app.WhatsizestructuringelementdoyouwanttousetodilatethemaskLabel.Text = 'Structuring element size for dilating the mask?';
            app.DilationPanel_SizeEditField = uieditfield(app.DilationPanel, 'numeric');
            app.DilationPanel_ContinueButton = uibutton(app.DilationPanel, 'push');
            app.DilationPanel_ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @DilationPanel_ContinueButtonPushed, true);
            app.DilationPanel_ContinueButton.Text = 'Continue';
            app.MaskingPanel = uipanel(app.UIFigure);
            app.MaskingPanel.Title = 'Mask Adjustment';
            app.Label = uilabel(app.MaskingPanel);
            app.Label.Text = 'Now, in the pop-up window adjust the mask by drawing a tight bounding box around the vessel.';
            app.Label_2 = uilabel(app.MaskingPanel);
            app.Label_2.Text = 'Double click your bounding box when done.';
            app.ThresholdPanel = uipanel(app.UIFigure);
            app.ThresholdPanel.Tooltip = {''};
            app.ThresholdPanel.Title = 'Threshold ';
            app.ThresholdValueEditFieldLabel = uilabel(app.ThresholdPanel);
            app.ThresholdValueEditFieldLabel.Text = 'Threshold Value';
            app.ThresholdPanelValueEditField = uieditfield(app.ThresholdPanel, 'numeric');
            app.ThresholdPanelValueEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdPanelValueEditFieldValueChanged, true);
            app.ThresholdPanelValueEditField.Tooltip = {'Enter 0 to use a seperate threshold calculated for each image. Enter an integer < 0 to use a spatially varying threshold for each image. The absolute value of this number is the size of the dilating element.'};
            app.ThresholdPanelLabel_2 = uilabel(app.ThresholdPanel);
            app.ThresholdPanelLabel_2.Text = 'First, specify the the Threshold Value below to use for the segmentation. You can use the pop-up window to help you choose the Threshold Value.';
            app.ThresholdPanelBackButton = uibutton(app.ThresholdPanel, 'push');
            app.ThresholdPanelBackButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdPanelBackButtonPushed, true);
            app.ThresholdPanelBackButton.Text = 'Back';
            app.ThresholdPanelContinueButton = uibutton(app.ThresholdPanel, 'push');
            app.ThresholdPanelContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdPanelContinueButtonPushed, true);
            app.ThresholdPanelContinueButton.Text = 'Continue';
            app.ThresholdPanelLabel_3 = uilabel(app.ThresholdPanel);
            app.ThresholdPanelLabel_3.Text = 'Click Continue when you are done.';
            app.CroppingPanel = uipanel(app.UIFigure);
            app.CroppingPanel.Title = 'Cropping';
            app.InthepopupwindowselectaregionandthendoubleclicktoconfirmLabel = uilabel(app.CroppingPanel);
            app.InthepopupwindowselectaregionandthendoubleclicktoconfirmLabel.Text = 'In the pop-up window, select a region and then double click to confirm.';
            app.HomePanel = uipanel(app.UIFigure);
            app.HomePanel.Title = 'Vessel Diameter and Pulsatility Analysis App';
            app.HomePanel_Label1 = uilabel(app.HomePanel);
            app.HomePanel_Label1.Text = 'Vessel Diameter and Pulsatility Analysis App';
            app.SelectDirectoryTextArea = uitextarea(app.HomePanel);
            app.SelectDirectoryButton = uibutton(app.HomePanel, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.HomePanel_ContinueButton = uibutton(app.HomePanel, 'push');
            app.HomePanel_ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @HomePanel_ContinueButtonPushed, true);
            app.HomePanel_ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end